classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HW4Tab                     matlab.ui.container.Tab
        REFLECTButton              matlab.ui.control.Button
        REFLECTButtonGroup         matlab.ui.container.ButtonGroup
        VERTICALButton             matlab.ui.control.RadioButton
        HORIZONTALButton           matlab.ui.control.RadioButton
        EditFieldShift             matlab.ui.control.NumericEditField
        SHIFTButtonGroup           matlab.ui.container.ButtonGroup
        DOWNButton                 matlab.ui.control.RadioButton
        UPButton                   matlab.ui.control.RadioButton
        LEFTButton                 matlab.ui.control.RadioButton
        RIGHTButton                matlab.ui.control.RadioButton
        SHIFTButton                matlab.ui.control.Button
        HEIGHTEditField            matlab.ui.control.NumericEditField
        HEIGHTEditFieldLabel       matlab.ui.control.Label
        WIDTHEditField             matlab.ui.control.NumericEditField
        WIDTHEditFieldLabel        matlab.ui.control.Label
        RESIZEButton               matlab.ui.control.Button
        CROPButton                 matlab.ui.control.Button
        Y2EditFieldCrop            matlab.ui.control.NumericEditField
        Y2EditFieldLabel           matlab.ui.control.Label
        X2EditFieldCrop            matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        Y1EditFieldCrop            matlab.ui.control.NumericEditField
        Y1EditFieldLabel           matlab.ui.control.Label
        X1EditFieldCrop            matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        SETTRANSFERFUNCTIONButton  matlab.ui.control.Button
        BlackSlider                matlab.ui.control.Slider
        BlackSliderLabel           matlab.ui.control.Label
        WhiteSilder                matlab.ui.control.Slider
        WhiteSilderSliderLabel     matlab.ui.control.Label
        OUTPUTIMAGE                matlab.ui.control.Image
        OUTPUTIMAGELabel           matlab.ui.control.Label
        INPUTIMAGELabel            matlab.ui.control.Label
        INPUTIMAGE                 matlab.ui.control.Image
        APPLYA2HButton             matlab.ui.control.Button
        MEDIANButton_2             matlab.ui.control.Button
        AVERAGEButton_2            matlab.ui.control.Button
        MAXButton_2                matlab.ui.control.Button
        MINButton_2                matlab.ui.control.Button
        FILTERLabel_2              matlab.ui.control.Label
        EQUALIZATIONButton         matlab.ui.control.Button
        STRETCHINGButton           matlab.ui.control.Button
        RGBHSIButton               matlab.ui.control.Button
        CHOOSEFILEButton           matlab.ui.control.Button
        HW5Tab                     matlab.ui.container.Tab
        NotchRejectFilterButton    matlab.ui.control.Button
        HighPassFilterButton       matlab.ui.control.Button
        UIAxes3_4                  matlab.ui.control.UIAxes
        UIAxes3_3                  matlab.ui.control.UIAxes
        UIAxes3_2                  matlab.ui.control.UIAxes
        UIAxes3_1                  matlab.ui.control.UIAxes
        HW6Tab                     matlab.ui.container.Tab
        Label                      matlab.ui.control.Label
        LabelAccurayPercentage     matlab.ui.control.Label
        AccuracyLabel              matlab.ui.control.Label
        PERIODICButton             matlab.ui.control.Button
        GAUSSIANButton             matlab.ui.control.Button
        SALTPEPPERButton           matlab.ui.control.Button
        LOADIMAGEButton            matlab.ui.control.Button
        UIAxesRemovedNoise         matlab.ui.control.UIAxes
        UIAxesAddedNoise           matlab.ui.control.UIAxes
        UIAxesOriginal             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        filepath;
        readingfile;
        picture;
        noisy_image;
        original_image;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHOOSEFILEButton
        function CHOOSEFILEButtonPushed(app, event)
            [Filename, Pathname] = uigetfile({'*.jpg';'*.png';'*.tif';'*.bmp'},'File Selector');
            if Filename == 0
                warndlg('You did not choose an image file');
            end

            app.filepath = fullfile(Pathname, Filename);
            app.readingfile = imread(app.filepath);
            imwrite(app.readingfile, 'inputImage.png')

            size_image = size(app.readingfile);
            if length(size_image) ~= 3
                zero = zeros(size_image(1), size_image(2), 3);
                for i = 1:size_image(1)
                    for j = 1:size_image(2)
                        zero(i, j, 1) = app.readingfile(i, j);
                        zero(i, j, 2) = app.readingfile(i, j);
                        zero(i, j, 3) = app.readingfile(i, j);
                    end
                end
                app.readingfile = uint8(zero);
            end

            app.INPUTIMAGE.ImageSource = app.readingfile;

        end

        % Image clicked function: INPUTIMAGE
        function INPUTIMAGEImageClicked(app, event)

        end

        % Button pushed function: REFLECTButton
        function REFLECTButtonPushed(app, event)
            if(app.VERTICALButton.Value == 1)
                app.picture=img_reflection(app.readingfile,'vertical');
            end
            if(app.HORIZONTALButton.Value == 1)
                app.picture=img_reflection(app.readingfile,'horizontal');
            end
            imwrite(app.picture,'reflectionImage.png')
            app.OUTPUTIMAGE.ImageSource = 'reflectionImage.png';

        end

        % Button pushed function: SHIFTButton
        function SHIFTButtonPushed(app, event)
            app.picture=img_shift(app.readingfile,app.DOWNEditField.Value,app.RIGHTEditField.Value);
            imwrite(app.picture,'shiftedImage.png')
            app.OUTPUTIMAGE.ImageSource = 'shiftedImage.png';


        end

        % Button pushed function: RESIZEButton
        function RESIZEButtonPushed(app, event)
            app.picture=resize_img(app.readingfile,app.WIDTHEditField.Value,app.WIDTHEditField.Value);
            imwrite(app.picture,'resizedImage.png')
            app.OUTPUTIMAGE.ImageSource = 'resizedImage.png';
        end

        % Button pushed function: RGBHSIButton
        function RGBHSIButtonPushed(app, event)
            app.picture = rgb2hsi_img(app.readingfile);
            imwrite(app.picture,'hsi.png')
            app.OUTPUTIMAGE.ImageSource = 'hsi.png';

        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            app.picture=crop_img(app.readingfile,app.X1EditFieldCrop.Value,app.Y1EditFieldCrop.Value,app.X2EditFieldCrop.Value,app.Y2EditFieldCrop.Value);
            imwrite(app.picture,'cropImaged.png')
            app.OUTPUTIMAGE.ImageSource = 'cropImaged.png';
        end

        % Button pushed function: STRETCHINGButton
        function STRETCHINGButtonPushed(app, event)
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);
            image_red = image(:,:,1);
            image_green = image(:,:,2);
            image_blue = image(:,:,3);

            min_red = min(min(image_red));
            max_red = max(max(image_red));
            min_green = min(min(image_green));
            max_green = max(max(image_green));
            min_blue = min(min(image_blue));
            max_blue = max(max(image_blue));

            for i = 1:size_image(1)
                for j = 1:size_image(2)
                    image_reds = ((250 / (max_red - min_red)) * (image_red(i, j) - min_red));
                    image_greens = ((250 / (max_green - min_green)) * (image_green(i, j) - min_green));
                    image_blues = ((250 / (max_blue - min_blue)) * (image_blue(i, j) - min_blue));
                    lastimage(i,j,1) = uint8(image_reds);
                    lastimage(i,j,2) = uint8(image_greens);
                    lastimage(i,j,3) = uint8(image_blues);
                end
            end
            app.OUTPUTIMAGE.ImageSource = lastimage;
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: EQUALIZATIONButton
        function EQUALIZATIONButtonPushed(app, event)
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);

            if length(size_image) == 3
                image = rgb2gray(image);
            end

            image_size = size(image);
            image = double(image);

            % Loop for Getting the Histogram of the image
            histogram = zeros(1, 256);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    for k = 0:255
                        if image(i, j) == k
                            histogram(k + 1) = histogram(k + 1) + 1;
                        end
                    end
                end
            end

            % Generating PDF out of histogram by diving by total no. of pixels
            pdf = (1 / (image_size(1) * image_size(2))) * histogram;

            % Generating CDF out of PDF
            cdf = zeros(1, 256);
            cdf(1) = pdf(1);
            for i = 2:256
                cdf(i) = cdf(i - 1) + pdf(i);
            end
            cdf = round(255 * cdf);
            ep = zeros(image_size);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    t = (image(i, j) + 1);
                    ep(i, j) = cdf(t);
                end
            end

            % Loop for Getting the Histogram of the image
            histogram_2 = zeros(1,256);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    for k = 0:255
                        if ep(i,j) == k
                            histogram_2(k + 1) = histogram_2(k + 1) + 1;
                        end
                    end
                end
            end
            size_ep = size(ep);
            zero = zeros(size_ep(1), size_ep(2), 3);
            for i = 1:size_ep(1)
                for j = 1:size_ep(2)
                    zero(i,j,1) = ep(i,j);
                    zero(i,j,2) = ep(i,j);
                    zero(i,j,3) = ep(i,j);
                end
            end

            app.OUTPUTIMAGE.ImageSource = uint8(zero);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: SETTRANSFERFUNCTIONButton
        function SETTRANSFERFUNCTIONButtonPushed(app, event)
            blacklevel = app.BlackSlider.Value;
            whitelevel = app.WhiteSilder.Value;
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);

            b = size(image);
            image = double(image);

            if length(size_image) == 3
                hist1r = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 1) == k
                                hist1r(k + 1) = hist1r(k + 1) + 1;
                            end
                        end
                    end
                end

                hist1g = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 2) == k
                                hist1g(k + 1) = hist1g(k + 1) + 1;
                            end
                        end
                    end
                end

                hist1b = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 3) == k
                                hist1b(k + 1) = hist1b(k + 1) + 1;
                            end
                        end
                    end
                end

                ep1 = image(:, :, 1);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep1(i, j) < blacklevel
                            ep1(i, j) = 0;
                        elseif ep1(i, j) > whitelevel
                            ep1(i, j) = 255;
                        end
                        ep(i, j, 1) = ep1(i, j);
                    end
                end

                ep2 = image(:, :, 2);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep2(i, j) < blacklevel
                            ep2(i, j)=0;
                        elseif ep2(i, j) > whitelevel
                            ep2(i, j)=255;
                        end
                        ep(i, j, 2) = ep2(i, j);
                    end
                end

                ep3 = image(:, :, 3);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep3(i, j) < blacklevel
                            ep3(i, j) = 0;
                        elseif ep3(i, j) > whitelevel
                            ep3(i, j) = 255;
                        end
                        ep(i, j, 3) = ep3(i, j);
                    end
                end
                app.OUTPUTIMAGE.ImageSource = uint8(ep);
            else
                hist1 = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i,j) == k
                                hist1(k+1) = hist1(k + 1) + 1;
                            end
                        end
                    end
                end

                ep = image;
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep(i, j) < blacklevel
                            ep(i, j) = 0;
                        elseif ep(i, j) > whitelevel
                            ep(i, j) = 255;
                        end
                    end
                end

                hist2 = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if ep(i, j) == k
                                hist2(k + 1) = hist2(k + 1) + 1;
                            end
                        end
                    end
                end
                app.OUTPUTIMAGE.ImageSource = uint8(ep);
            end

            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: AVERAGEButton_2
        function AVERAGEButton_2Pushed(app, event)
            image = imread(app.filepath);

            %             median_filter = ones(3, 3) / 9;
            %             k_average_image = imfilter(image, median_filter);
            %             image_out = cat(3, k_average_image, k_average_image, k_average_image);

            [row, column] = size(image);
            result_image = zeros(row, column);

            for i = 1:row
                for j = 1:column
                    % We set the neighborhood boundaries here
                    row_min = max(1, i - 1);
                    row_max = min(row, i + 1);
                    column_min = max(1, j - 1);
                    column_max = min(column, j + 1);

                    % Now the neighborhood matrix, denoting it by temp
                    temp = image(row_min:row_max, column_min:column_max);

                    % Now the i'th pixel of the output will be the average
                    % of this neighborhood.
                    result_image(i, j) = mean(temp(:));
                end
            end

            result_image = cat(3, result_image, result_image, result_image);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MEDIANButton_2
        function MEDIANButton_2Pushed(app, event)
            image = imread(app.filepath);

            [~, ~, numberOfColorChannels] = size(image);
            if numberOfColorChannels > 1
                %                 original_image_red_channel = image(:, :, 1);
                %                 original_image_green_channel = image(:, :, 2);
                %                 original_image_blue_channel = image(:, :, 3);
                %
                %                 red_channel = medfilt2(original_image_red_channel, [3 3]);
                %                 green_channel = medfilt2(original_image_green_channel, [3 3]);
                %                 blue_channel = medfilt2(original_image_blue_channel, [3 3]);
                %
                %                 result_image = cat(3, red_channel, green_channel, blue_channel);
                result_image = median_filter_to_image(image);
            else
                %                 original_image_gray_scale = image(:, :, 1);
                %
                %                 gray_scale = medfilt2(original_image_gray_scale, [3 3]);
                %                 result_image = cat(3, gray_scale, gray_scale, gray_scale);

                result_image = image;
                [row, column] = size(image);
                for i = 2:row-1
                    for j = 2:column-1
                        mat = [image(i - 1, j - 1), image(i - 1, j), image(i - 1, j + 1), ...
                            image(i, j - 1), image(i, j), image(i, j + 1), ...
                            image(i + 1, j - 1), image(i + 1, j), image(i + 1, j + 1)];
                        mat = sort(mat);
                        result_image(i, j) = mat(5);
                    end
                end
            end
            result_image = cat(3, result_image, result_image, result_image);

            app.OUTPUTIMAGE.ImageSource = result_image;
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MAXButton_2
        function MAXButton_2Pushed(app, event)
            image = imread(app.filepath);

            % PREALLOCATE THE OUTPUT MATRIX
            B = zeros(size(image));

            % PAD THE MATRIX A WITH ZEROS
            modifyA = padarray(image, [1 1]);

            x = (1:3)';
            y = (1:3)';

            for i = 1:size(modifyA, 1) - 2
                for j = 1:size(modifyA, 2) - 2

                    % VECTORIZED METHOD
                    window = reshape(modifyA(i + x - 1, j + y - 1), [], 1);

                    % FIND THE MAXIMUM VALUE IN THE SELECTED WINDOW
                    B(i, j) = max(window);
                end
            end

            % CONVERT THE OUTPUT MATRIX TO 0-255 RANGE IMAGE TYPE
            result_image = cat(3, B, B, B);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MINButton_2
        function MINButton_2Pushed(app, event)
            image = imread(app.filepath);

            % PREALLOCATE THE OUTPUT MATRIX
            B = zeros(size(image));

            % PAD THE MATRIX A WITH ZEROS
            modifyA = padarray(image, [1 1]);

            x = (1:3)';
            y = (1:3)';

            for i = 1:size(modifyA, 1) - 2
                for j = 1:size(modifyA, 2) - 2

                    % VECTORIZED METHOD
                    window = reshape(modifyA(i + x - 1, j + y - 1), [], 1);

                    % FIND THE MAXIMUM VALUE IN THE SELECTED WINDOW
                    B(i, j) = min(window);
                end
            end

            % CONVERT THE OUTPUT MATRIX TO 0-255 RANGE IMAGE TYPE
            result_image = cat(3, B, B, B);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: APPLYA2HButton
        function APPLYA2HButtonPushed(app, event)

            %             image = imread(app.filepath);
            %
            %             figure (1); subplot (2,2,1);
            %             imshow(image); title ('a) original image');
            %
            %
            %             laplacian_mask = [0 1 0; 1 -4 1; 0 1 0];
            %             image_laplacian = conv2(image, laplacian_mask, 'same');
            %             image_laplacian = image + uint8(image_laplacian);
            %             figure (1); subplot(2,2,2);
            %             imshow(image_laplacian ); title ('b) laplacian enhanced image');
            %
            %             b = imadd(image, image_laplacian);
            %             figure (1); subplot (2,2,3);
            %             imshow(b); title ('c) sharpened image');
            %
            %             c = edge (b,'sobel',0.1);
            %             figure (1); subplot (2,2,4);
            %             imshow(c); title ('d) sobel gradient of image');
            %
            %             d = imboxfilt(uint32(c), 5);
            %             figure (2); subplot (2, 2, 1);
            %             imshow(d); title ('e) smoothed of image');
            %
            %             f= immultiply(b, image_laplacian);
            %             figure (2); subplot (2,2,2);
            %             imshow(f); title ('f) mask image');
            %
            %             g= imadd (image, f);
            %             figure (2); subplot (2,2,3);
            %             imshow(g); title ('g) sharpened image');
            %
            %             c=1;
            %             gamma = 5;
            %             h1=double(g);
            %             h=c*(h1.*gamma);
            %             figure (2); subplot (2,2,4);
            %
            %             imshow(h); title ('h) power law transformation');

            Ia = im2double(imread(app.filepath));

            Ib = imfilter(Ia, -fspecial('laplacian', 0.5) * 3);
            Ic = Ia + Ib;
            Id = abs(imfilter(Ia, fspecial('sobel'))) + ...
                abs(imfilter(Ia, fspecial('sobel')'));
            Ie = imfilter(Id, fspecial('average',5));
            If = Ib .* Ie;
            Ig = Ia + If;
            Ih = imadjust(Ig, [], [], 0.5);

            figure
            imshow(Ia, [])
            title('Original image (a)')
            figure
            imshow(Ib, [])
            title('Image in (b)')

            figure
            imshow(Ic, [])
            title('Image in (c)')

            figure
            imshow(Id, [])
            title('Image in (d)')

            figure
            imshow(Ie, [])
            title('Image in (e)')

            figure
            imshow(If, [])
            title('Image in (f)')

            figure
            imshow(Ig, [])
            title('Image in (g)')
            figure
            imshow(Ih, [])
            title('Image in (h)')

        end

        % Button pushed function: NotchRejectFilterButton
        function NotchRejectFilterButtonPushed(app, event)
            % image is readed of HW5_2 image .
            % Compute the 2-D Fourier transform of the image data.
            % Shift the zero-frequency component to the center of the output,
            % and plot the resulting matrix, which is the same size as image.
            image = imread("HW5_2.tif");
            shifted_fft_image = fftshift(fft2(image));

            % we apply ABS because log of negative number is undefined
            % we apply LOG because, Bazı yoğunlukların diğerlerinden daha
            % yüksek büyüklük sıraları olması muhtemeldir. Bir günlük 
            % ölçeği uygulamak, daha küçük değerleri de görmenizi sağlar.
            % Aksi takdirde, görüntü sıkıcı olacaktır.
            % we apply MAT2GRAY because, mat2gray() kullanarak pikselleri 
            % ölçeklendiririz ve ardından uint8'e dönüştürürüz.
            f_gray_image = mat2gray(log(abs(shifted_fft_image) + 1));

            % Created a mask for notch filtering.
            [x, y] = size(image);
            notch_mask = ones(x, y);
            notch_mask(59:63, :) = 0;
            notch_mask(:, 13:15) = 0;
            notch_mask(:, 39:41) = 0;
            notch_mask(:, 63:66) = 0;
            notch_mask(:, 86:88) = 0;
            notch_mask(:, 112:114) = 0;
            notch_mask(:, 138:141) = 0;
            notch_mask(:, 162:167) = 0;
            notch_mask(:, 189:192) = 0;
            notch_mask(:, 216:218) = 0;
            notch_mask(:, 237:239) = 0;
            notch_mask(:, 263:265) = 0;
            notch_mask(:, 289:291) = 0;
            notch_mask(:, 342:344) = 0;
            notch_mask(:, 367:370) = 0;
            notch_mask(:, 388:390) = 0;
            notch_mask(:, 414:416) = 0;
            notch_mask(:, 440:443) = 0;
            notch_mask(:, 466:468) = 0;
            notch_mask(:, 492:494) = 0;
            notch_mask(:, 516:520) = 0;
            notch_mask(:, 544:546) = 0;
            notch_mask(:, 565:568) = 0;
            notch_mask(:, 591:593) = 0;
            notch_mask(:, 617:619) = 0;
            notch_mask(:, 316) = 0;
            notch_mask(220:250, 316) = 1;

            % Butterworth notch reject filter multiplied by the Fourier transform.
            notch_rejected_image = shifted_fft_image .* notch_mask;
            % inverse fourier transform 2-D.
            inversed_image = ifft2(notch_rejected_image);
            % we can get filtered image.
            filtered_image = mat2gray((1 + abs(inversed_image)));

            de = fftshift(fft2(filtered_image));
            notched_spectrum = mat2gray(log(1 + abs(de)));

            title(app.UIAxes3_1, {'Original Image'})
            title(app.UIAxes3_2, {'Spectrum'})
            title(app.UIAxes3_3, {'Butterworth Notch Reject Filter Multiplied by The Fourier Transform'})
            title(app.UIAxes3_4, {'Filtered Image'})

            imshow(image, 'Parent', app.UIAxes3_1);
            imshow(f_gray_image, 'Parent', app.UIAxes3_2);
            imshow(notched_spectrum, 'Parent', app.UIAxes3_3);
            imshow(filtered_image, 'Parent', app.UIAxes3_4);
        end

        % Button pushed function: HighPassFilterButton
        function HighPassFilterButtonPushed(app, event)
            image = imread('HW5_1.tif');
            % It is easy to implement these in Matlab; here are the 
            % commands to produce a Butterworth low pass filter 
            % with D = 50 and order n = 4.
            % change the name for d0, d is usually the (u²+v²)⁽1/2)
            amplitude = 1;
            d0_cutoff_freq = 50;
            n_order = 4;

            x = -513:512;
            y = -481:480;
            [X, Y] = meshgrid(x, y);
            
            % z is circle matrix
            z = 1 ./ (1 + (d0_cutoff_freq ./ sqrt(X.^2 + Y.^2)).^(2 * n_order));
            % cf is center shifted transformed matrix
            cf = fftshift(fft2(image));
            % cfh is element-wise product of two matrices
            cfh = cf .* z;

            filter = mat2gray(log(1 + abs(cfh)));
            cfhi = real(ifft2(double(cfh)));
            HPFI = (uint8(abs(cfhi)));

            % is easy to get the h and w like this
            [M, N] = size(image);

            % compute the 2d fourier transform in order to multiply
            F = fft2(double(image));

            % Compute filter and do the meshgrid for image matrix but 
            % it is M * N, and get only the real part.
            u = 0:(M - 1);
            v = 0:(N - 1);

            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            [V, U] = meshgrid(v, u);
            D = sqrt(V.^2 + U.^2);
            H = amplitude * (1 ./ (1 + (d0_cutoff_freq ./ D).^(2 * n_order)));

            % multiply element by element
            G = H .* F;
            S = mat2gray(log(1 + abs(G)));
            new_image = ifft2(double(G));

            title(app.UIAxes3_1, {'Thumb print'})
            title(app.UIAxes3_2, {'Spectrum'})
            title(app.UIAxes3_3, {'Result of Highpass Filtering'})
            title(app.UIAxes3_4, {'Result of Thresholding'})

            imshow(image, 'Parent', app.UIAxes3_1);
            imshow(filter, 'Parent', app.UIAxes3_2);
            imshow(HPFI, 'Parent', app.UIAxes3_3);
            imshow(new_image , 'Parent', app.UIAxes3_4);
        end

        % Button pushed function: LOADIMAGEButton
        function LOADIMAGEButtonPushed(app, event)
            [path, file] = uigetfile({'*.jpg; *.bmp; *.gif; *.tiff; *.Tiff; *.Tif; *.tif; *.jpeg; *.png'}, 'Select file');
            app.picture = [file path];
            app.original_image = imread(app.picture);
            imshow(app.original_image, 'Parent', app.UIAxesOriginal);
        end

        % Button pushed function: SALTPEPPERButton
        function SALTPEPPERButtonPushed(app, event)
            image = app.original_image;
            app.noisy_image = imnoise(app.original_image, 'salt & pepper', 0.25);
            imshow(app.noisy_image, 'Parent', app.UIAxesAddedNoise);

            redChannel = app.noisy_image(:, :, 1);
            greenChannel = app.noisy_image(:, :, 2);
            blueChannel = app.noisy_image(:, :, 3);

            % Median Filter applied to channels.
            redMF = medfilt2(redChannel, [3 3]);
            greenMF = medfilt2(greenChannel, [3 3]);
            blueMF = medfilt2(blueChannel, [3 3]);

            % Find the noise in the red.
            noiseImage = (redChannel == 0 | redChannel == 255);
            % Get rid of the noise in the red by replacing with median.
            noiseFreeRed = redChannel;
            noiseFreeRed(noiseImage) = redMF(noiseImage);

            % Find the noise in the green.
            noiseImage = (greenChannel == 0 | greenChannel == 255);
            % Get rid of the noise in the green by replacing with median.
            noiseFreeGreen = greenChannel;
            noiseFreeGreen(noiseImage) = greenMF(noiseImage);

            % Find the noise in the blue.
            noiseImage = (blueChannel == 0 | blueChannel == 255);
            % Get rid of the noise in the blue by replacing with median.
            noiseFreeBlue = blueChannel;
            noiseFreeBlue(noiseImage) = blueMF(noiseImage);

            % Reconstruct the noise free RGB image
            new_image = cat(3, noiseFreeRed, noiseFreeGreen, noiseFreeBlue);
            imshow(new_image, 'Parent', app.UIAxesRemovedNoise);

            th = 16;
            [row, col] = size(image);
            same_pixels = 0;
            for i = 1:row
                for j = 1:col
                    dif = int16(image(i, j)) - int16(new_image(i, j));
                    dif = abs(dif);

                    if image(i, j) == new_image(i, j) || (dif < th)
                        same_pixels = same_pixels + 1;
                    end
                end
            end

            totalPxs = int16((row * col) / 100);
            same_pixels = int16(same_pixels / 100);
           
            app.LabelAccurayPercentage.Text = sprintf('%.1f', ((sprintf('%.1f', same_pixels) / sprintf('%.1f', totalPxs)) * 100));
        end

        % Button pushed function: GAUSSIANButton
        function GAUSSIANButtonPushed(app, event)
            image = app.original_image;
            app.noisy_image = imnoise(app.original_image, 'gaussian', 0,0.02);
            imshow(app.noisy_image, 'Parent', app.UIAxesAddedNoise);

            new_image = zeros(size(app.noisy_image));
            modifyA = padarray(app.noisy_image, [1 1]);
            x = (1:3)';
            y = (1:3)';
            for i = 1:size(modifyA, 1) - 2
                for j = 1:size(modifyA, 2) - 2
                    window = reshape(modifyA(i + x - 1, j + y - 1), [], 1);
                    new_image(i, j, :) = mean(window);
                end
            end

            new_image = uint8(new_image);
            imshow(new_image, 'Parent', app.UIAxesRemovedNoise);

            th = 16;
            [row, col] = size(image);
            samePxs = 0;
            for i = 1:row
                for j = 1:col
                    dif = int16(image(i, j)) - int16(new_image(i, j));
                    dif = abs(dif);

                    if image(i, j) == new_image(i, j) || (dif < th)
                        samePxs = samePxs + 1;
                    end
                end
            end

            totalPxs = int16((row * col) / 100);
            samePxs = int16(samePxs / 100);
           
            app.LabelAccurayPercentage.Text = sprintf('%.1f', ((sprintf('%.1f', samePxs) / sprintf('%.1f', totalPxs)) * 100));
        end

        % Button pushed function: PERIODICButton
        function PERIODICButtonPushed(app, event)
            image = imread('cameraman.tif');
            image = im2gray(image);
            sizeImage = size(image);
            [x, y] = meshgrid(1:sizeImage(1), 1:sizeImage(2));
            mask = 1 + sin(x + y / 1.5);
            mask1 = (im2double(image) + mask) / 8;
            imshow(mask1, 'Parent', app.UIAxesAddedNoise);

            f_image = fftshift(fft2(mask1));

            [x, y] = size(mask);
            matrix = ones(x, y);

            matrix(:, 87:89) = 0;
            matrix(:, 169:171) = 0;
            matrix(101:103, :) = 0;
            matrix(155:157, :) = 0;

            bf = f_image .* matrix;
            s = ifft2(bf);
            newimage = mat2gray((1 + abs(s)));
            de = fftshift(fft2(newimage));
            imshow(newimage, 'Parent', app.UIAxesRemovedNoise);

            th = 16;
            [row, col] = size(image);
            samePxs = 0;
            for i = 1:row
                for j = 1:col
                    dif = int16(image(i, j)) - int16(newimage(i, j));
                    dif = abs(dif);

                    if image(i, j) == newimage(i, j) || (dif < th)
                        samePxs = samePxs + 1;
                    end
                end
            end

            totalPxs = int16((row * col) / 100);
            samePxs = int16(samePxs / 100);
           
            app.LabelAccurayPercentage.Text = sprintf('%.1f', ((sprintf('%.1f',samePxs) / sprintf('%.1f',totalPxs)) * 100));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HW4Tab = uitab(app.TabGroup);
            app.HW4Tab.Title = 'HW4';
            app.CHOOSEFILEButton = uibutton(app.HW4Tab, 'push');
            app.CHOOSEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFILEButtonPushed, true);
            app.CHOOSEFILEButton.Text = 'CHOOSE FILE';
            app.RGBHSIButton = uibutton(app.HW4Tab, 'push');
            app.RGBHSIButton.ButtonPushedFcn = createCallbackFcn(app, @RGBHSIButtonPushed, true);
            app.RGBHSIButton.Text = 'RGB -> HSI';
            app.STRETCHINGButton = uibutton(app.HW4Tab, 'push');
            app.STRETCHINGButton.ButtonPushedFcn = createCallbackFcn(app, @STRETCHINGButtonPushed, true);
            app.STRETCHINGButton.Text = 'STRETCHING';
            app.EQUALIZATIONButton = uibutton(app.HW4Tab, 'push');
            app.EQUALIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @EQUALIZATIONButtonPushed, true);
            app.EQUALIZATIONButton.Text = 'EQUALIZATION';
            app.FILTERLabel_2 = uilabel(app.HW4Tab);
            app.FILTERLabel_2.Text = 'FILTER';
            app.MINButton_2 = uibutton(app.HW4Tab, 'push');
            app.MINButton_2.ButtonPushedFcn = createCallbackFcn(app, @MINButton_2Pushed, true);
            app.MINButton_2.Text = 'MIN';
            app.MAXButton_2 = uibutton(app.HW4Tab, 'push');
            app.MAXButton_2.ButtonPushedFcn = createCallbackFcn(app, @MAXButton_2Pushed, true);
            app.MAXButton_2.Text = 'MAX';
            app.AVERAGEButton_2 = uibutton(app.HW4Tab, 'push');
            app.AVERAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @AVERAGEButton_2Pushed, true);
            app.AVERAGEButton_2.Text = 'AVERAGE';
            app.MEDIANButton_2 = uibutton(app.HW4Tab, 'push');
            app.MEDIANButton_2.ButtonPushedFcn = createCallbackFcn(app, @MEDIANButton_2Pushed, true);
            app.MEDIANButton_2.Text = 'MEDIAN';
            app.APPLYA2HButton = uibutton(app.HW4Tab, 'push');
            app.APPLYA2HButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYA2HButtonPushed, true);
            app.APPLYA2HButton.Text = 'APPLY A2H';
            app.INPUTIMAGE = uiimage(app.HW4Tab);
            app.INPUTIMAGE.ImageClickedFcn = createCallbackFcn(app, @INPUTIMAGEImageClicked, true);
            app.INPUTIMAGELabel = uilabel(app.HW4Tab);
            app.INPUTIMAGELabel.Text = 'INPUT IMAGE';
            app.OUTPUTIMAGELabel = uilabel(app.HW4Tab);
            app.OUTPUTIMAGELabel.Text = 'OUTPUT IMAGE';
            app.OUTPUTIMAGE = uiimage(app.HW4Tab);
            app.WhiteSilderSliderLabel = uilabel(app.HW4Tab);
            app.WhiteSilderSliderLabel.Text = 'White Silder';
            app.WhiteSilder = uislider(app.HW4Tab);
            app.WhiteSilder.Limits = [0 255];
            app.BlackSliderLabel = uilabel(app.HW4Tab);
            app.BlackSliderLabel.Text = 'Black Slider';
            app.BlackSlider = uislider(app.HW4Tab);
            app.BlackSlider.Limits = [0 255];
            app.SETTRANSFERFUNCTIONButton = uibutton(app.HW4Tab, 'push');
            app.SETTRANSFERFUNCTIONButton.ButtonPushedFcn = createCallbackFcn(app, @SETTRANSFERFUNCTIONButtonPushed, true);
            app.SETTRANSFERFUNCTIONButton.Text = 'SET TRANSFER FUNCTION';
            app.X1EditFieldLabel = uilabel(app.HW4Tab);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditFieldCrop = uieditfield(app.HW4Tab, 'numeric');
            app.X1EditFieldCrop.Limits = [1 Inf];
            app.X1EditFieldCrop.Value = 1;
            app.Y1EditFieldLabel = uilabel(app.HW4Tab);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditFieldCrop = uieditfield(app.HW4Tab, 'numeric');
            app.Y1EditFieldCrop.Limits = [1 Inf];
            app.Y1EditFieldCrop.Value = 1;
            app.X2EditFieldLabel = uilabel(app.HW4Tab);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditFieldCrop = uieditfield(app.HW4Tab, 'numeric');
            app.X2EditFieldCrop.Limits = [1 Inf];
            app.X2EditFieldCrop.Value = 1;
            app.Y2EditFieldLabel = uilabel(app.HW4Tab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditFieldCrop = uieditfield(app.HW4Tab, 'numeric');
            app.Y2EditFieldCrop.Limits = [1 Inf];
            app.Y2EditFieldCrop.Value = 1;
            app.CROPButton = uibutton(app.HW4Tab, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.RESIZEButton = uibutton(app.HW4Tab, 'push');
            app.RESIZEButton.ButtonPushedFcn = createCallbackFcn(app, @RESIZEButtonPushed, true);
            app.RESIZEButton.Text = 'RESIZE';
            app.WIDTHEditFieldLabel = uilabel(app.HW4Tab);
            app.WIDTHEditFieldLabel.Text = 'WIDTH ';
            app.WIDTHEditField = uieditfield(app.HW4Tab, 'numeric');
            app.WIDTHEditField.Limits = [1 Inf];
            app.WIDTHEditField.Value = 1;
            app.HEIGHTEditFieldLabel = uilabel(app.HW4Tab);
            app.HEIGHTEditFieldLabel.Text = 'HEIGHT';
            app.HEIGHTEditField = uieditfield(app.HW4Tab, 'numeric');
            app.HEIGHTEditField.Limits = [1 Inf];
            app.HEIGHTEditField.Value = 1;
            app.SHIFTButton = uibutton(app.HW4Tab, 'push');
            app.SHIFTButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTButtonPushed, true);
            app.SHIFTButton.Text = 'SHIFT';
            app.SHIFTButtonGroup = uibuttongroup(app.HW4Tab);
            app.SHIFTButtonGroup.Title = 'SHIFT';
            app.RIGHTButton = uiradiobutton(app.SHIFTButtonGroup);
            app.RIGHTButton.Text = 'RIGHT';
            app.RIGHTButton.Value = true;
            app.LEFTButton = uiradiobutton(app.SHIFTButtonGroup);
            app.LEFTButton.Text = 'LEFT';
            app.UPButton = uiradiobutton(app.SHIFTButtonGroup);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uiradiobutton(app.SHIFTButtonGroup);
            app.DOWNButton.Text = 'DOWN';
            app.EditFieldShift = uieditfield(app.HW4Tab, 'numeric');
            app.EditFieldShift.Limits = [1 Inf];
            app.EditFieldShift.Value = 1;
            app.REFLECTButtonGroup = uibuttongroup(app.HW4Tab);
            app.REFLECTButtonGroup.Title = 'REFLECT';
            app.HORIZONTALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.HORIZONTALButton.Text = 'HORIZONTAL';
            app.HORIZONTALButton.Value = true;
            app.VERTICALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.VERTICALButton.Text = 'VERTICAL';
            app.REFLECTButton = uibutton(app.HW4Tab, 'push');
            app.REFLECTButton.ButtonPushedFcn = createCallbackFcn(app, @REFLECTButtonPushed, true);
            app.REFLECTButton.Text = 'REFLECT';
            app.HW5Tab = uitab(app.TabGroup);
            app.HW5Tab.Title = 'HW5';
            app.UIAxes3_1 = uiaxes(app.HW5Tab);
            app.UIAxes3_1.XTick = [];
            app.UIAxes3_1.YTick = [];
            app.UIAxes3_2 = uiaxes(app.HW5Tab);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_3 = uiaxes(app.HW5Tab);
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            app.UIAxes3_4 = uiaxes(app.HW5Tab);
            app.UIAxes3_4.XTick = [];
            app.UIAxes3_4.YTick = [];
            app.HighPassFilterButton = uibutton(app.HW5Tab, 'push');
            app.HighPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilterButtonPushed, true);
            app.HighPassFilterButton.Text = 'HIGHPASS AND THRESHOLD';
            app.NotchRejectFilterButton = uibutton(app.HW5Tab, 'push');
            app.NotchRejectFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchRejectFilterButtonPushed, true);
            app.NotchRejectFilterButton.Text = 'NOTCH REJECT FILTER';
            app.HW6Tab = uitab(app.TabGroup);
            app.HW6Tab.Title = 'HW6';
            app.UIAxesOriginal = uiaxes(app.HW6Tab);
            title(app.UIAxesOriginal, 'ORIGINAL IMAGE')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesAddedNoise = uiaxes(app.HW6Tab);
            title(app.UIAxesAddedNoise, 'ADDED NOISE')
            app.UIAxesAddedNoise.XTick = [];
            app.UIAxesAddedNoise.YTick = [];
            app.UIAxesRemovedNoise = uiaxes(app.HW6Tab);
            title(app.UIAxesRemovedNoise, 'REMOVED NOISE')
            app.UIAxesRemovedNoise.XTick = [];
            app.UIAxesRemovedNoise.YTick = [];
            app.LOADIMAGEButton = uibutton(app.HW6Tab, 'push');
            app.LOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADIMAGEButtonPushed, true);
            app.LOADIMAGEButton.Text = 'LOAD IMAGE';
            app.SALTPEPPERButton = uibutton(app.HW6Tab, 'push');
            app.SALTPEPPERButton.ButtonPushedFcn = createCallbackFcn(app, @SALTPEPPERButtonPushed, true);
            app.SALTPEPPERButton.Text = 'SALT & PEPPER';
            app.GAUSSIANButton = uibutton(app.HW6Tab, 'push');
            app.GAUSSIANButton.ButtonPushedFcn = createCallbackFcn(app, @GAUSSIANButtonPushed, true);
            app.GAUSSIANButton.Text = 'GAUSSIAN';
            app.PERIODICButton = uibutton(app.HW6Tab, 'push');
            app.PERIODICButton.ButtonPushedFcn = createCallbackFcn(app, @PERIODICButtonPushed, true);
            app.PERIODICButton.Text = 'PERIODIC';
            app.AccuracyLabel = uilabel(app.HW6Tab);
            app.AccuracyLabel.Text = 'Accuracy:';
            app.LabelAccurayPercentage = uilabel(app.HW6Tab);
            app.LabelAccurayPercentage.Text = '0.00';
            app.Label = uilabel(app.HW6Tab);
            app.Label.Text = ' %';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end